classdef DM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ResetButton_3          matlab.ui.control.Button
        ResetBPMButton         matlab.ui.control.Button
        Lamp                   matlab.ui.control.Lamp
        StopButton             matlab.ui.control.Button
        BassdrumLabel          matlab.ui.control.Label
        SnaredrumLabel         matlab.ui.control.Label
        HighhatLabel           matlab.ui.control.Label
        ClapLabel              matlab.ui.control.Label
        ResetButton            matlab.ui.control.Button
        StartButton            matlab.ui.control.Button
        AccelerationKnob       matlab.ui.control.Knob
        AccelerationKnobLabel  matlab.ui.control.Label
        Switch_64              matlab.ui.control.ToggleSwitch
        Switch_63              matlab.ui.control.ToggleSwitch
        Switch_62              matlab.ui.control.ToggleSwitch
        Switch_61              matlab.ui.control.ToggleSwitch
        Switch_60              matlab.ui.control.ToggleSwitch
        Switch_59              matlab.ui.control.ToggleSwitch
        Switch_58              matlab.ui.control.ToggleSwitch
        Switch_57              matlab.ui.control.ToggleSwitch
        Switch_56              matlab.ui.control.ToggleSwitch
        Switch_55              matlab.ui.control.ToggleSwitch
        Switch_54              matlab.ui.control.ToggleSwitch
        Switch_53              matlab.ui.control.ToggleSwitch
        Switch_52              matlab.ui.control.ToggleSwitch
        Switch_51              matlab.ui.control.ToggleSwitch
        Switch_50              matlab.ui.control.ToggleSwitch
        Switch_49              matlab.ui.control.ToggleSwitch
        Switch_48              matlab.ui.control.ToggleSwitch
        Switch_47              matlab.ui.control.ToggleSwitch
        Switch_46              matlab.ui.control.ToggleSwitch
        Switch_45              matlab.ui.control.ToggleSwitch
        Switch_44              matlab.ui.control.ToggleSwitch
        Switch_43              matlab.ui.control.ToggleSwitch
        Switch_42              matlab.ui.control.ToggleSwitch
        Switch_41              matlab.ui.control.ToggleSwitch
        Switch_40              matlab.ui.control.ToggleSwitch
        Switch_39              matlab.ui.control.ToggleSwitch
        Switch_38              matlab.ui.control.ToggleSwitch
        Switch_37              matlab.ui.control.ToggleSwitch
        Switch_36              matlab.ui.control.ToggleSwitch
        Switch_35              matlab.ui.control.ToggleSwitch
        Switch_34              matlab.ui.control.ToggleSwitch
        Switch_33              matlab.ui.control.ToggleSwitch
        Switch_32              matlab.ui.control.ToggleSwitch
        Switch_31              matlab.ui.control.ToggleSwitch
        Switch_30              matlab.ui.control.ToggleSwitch
        Switch_29              matlab.ui.control.ToggleSwitch
        Switch_28              matlab.ui.control.ToggleSwitch
        Switch_27              matlab.ui.control.ToggleSwitch
        Switch_26              matlab.ui.control.ToggleSwitch
        Switch_25              matlab.ui.control.ToggleSwitch
        Switch_24              matlab.ui.control.ToggleSwitch
        Switch_23              matlab.ui.control.ToggleSwitch
        Switch_22              matlab.ui.control.ToggleSwitch
        Switch_21              matlab.ui.control.ToggleSwitch
        Switch_20              matlab.ui.control.ToggleSwitch
        Switch_19              matlab.ui.control.ToggleSwitch
        Switch_18              matlab.ui.control.ToggleSwitch
        Switch_17              matlab.ui.control.ToggleSwitch
        Switch_16              matlab.ui.control.ToggleSwitch
        Switch_15              matlab.ui.control.ToggleSwitch
        Switch_14              matlab.ui.control.ToggleSwitch
        Switch_13              matlab.ui.control.ToggleSwitch
        Switch_12              matlab.ui.control.ToggleSwitch
        Switch_11              matlab.ui.control.ToggleSwitch
        Switch_10              matlab.ui.control.ToggleSwitch
        Switch_9               matlab.ui.control.ToggleSwitch
        Switch_8               matlab.ui.control.ToggleSwitch
        Switch_7               matlab.ui.control.ToggleSwitch
        Switch_6               matlab.ui.control.ToggleSwitch
        Switch_5               matlab.ui.control.ToggleSwitch
        BPMKnob                matlab.ui.control.Knob
        BPMKnobLabel           matlab.ui.control.Label
        Switch_4               matlab.ui.control.ToggleSwitch
        Switch_3               matlab.ui.control.ToggleSwitch
        Switch_2               matlab.ui.control.ToggleSwitch
        Slider                 matlab.ui.control.Slider
        Switch_1               matlab.ui.control.ToggleSwitch
        Image                  matlab.ui.control.Image
        Image_15               matlab.ui.control.Image
        Image_14               matlab.ui.control.Image
        Image_2                matlab.ui.control.Image
        Image_3                matlab.ui.control.Image
        Image_4                matlab.ui.control.Image
        Image_5                matlab.ui.control.Image
        Image_13               matlab.ui.control.Image
        Image_12               matlab.ui.control.Image
        Image_11               matlab.ui.control.Image
        Image_10               matlab.ui.control.Image
        Image_9                matlab.ui.control.Image
        Image_8                matlab.ui.control.Image
        Image_7                matlab.ui.control.Image
        Image_6                matlab.ui.control.Image
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
                 for i = 1:64
                 SwitchName = sprintf('Switch_%d', i);
                 app.(SwitchName).Value = 'off';
                 end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.Lamp.Color ='green';
            BPM = app.BPMKnob.Value;
            num1 = (60/BPM)/4;
            for j = 1:1:30
            if num1 <= 0 || num1 == 10
            break;
            end
            k=1;
            %if strcmp(app.Lamp.Color, 'red')
            %fprintf('1');これはifを通っているかの確認用
            %break;
            %end
            for i = 1:1:16
            AT = app.AccelerationKnob.Value;
            app.Slider.Value = i;
            if app.Slider.Value == 1 && strcmp(app.Switch_1.Value, 'on')
            Clap();
            end
            if app.Slider.Value == 1 && strcmp(app.Switch_2.Value, 'on')
            HH();
            end
            if app.Slider.Value == 1 && strcmp(app.Switch_3.Value, 'on')
            Snare();
            end
            if app.Slider.Value == 1 && strcmp(app.Switch_4.Value, 'on')
            Bass();
            end
            if app.Slider.Value == 1 && j == 1
            pause(num1);
            continue;
            end
            if app.Slider.Value == 1 && j ~= 1
            AT = app.AccelerationKnob.Value;
            num2 = num1 * (AT/100);
            num1 = num1 - num2;
            pause(num1);
            continue;
            end
            if app.Slider.Value == i && strcmp(app.(sprintf('Switch_%d', k+4)).Value, 'on')
            Clap();
            end
            k = k+1;
            if app.Slider.Value == i && strcmp(app.(sprintf('Switch_%d', k+4)).Value, 'on')
            HH();
            end
            k = k+1;
            if app.Slider.Value == i && strcmp(app.(sprintf('Switch_%d', k+4)).Value, 'on')
            Snare();
            end
            k = k+1;
            if app.Slider.Value == i && strcmp(app.(sprintf('Switch_%d', k+4)).Value, 'on')
            Bass();
            end
            k = k+1;
            num2 = num1 * (AT/100);
            num1 = num1 - num2;
            if num1 <= 0 || num1 >= 5
            app.Lamp.Color ='red';
            break;
            end
            pause(num1);
            end
            end
            %BPMの値の変更はスタートボタンを押し直さないとできない仕様に変更
            %加速または減速しすぎるとランプを赤に点灯してプログラム停止
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.Lamp.Color ='red';
            %未実装
        end

        % Button pushed function: ResetBPMButton
        function ResetBPMButtonPushed(app, event)
            app.BPMKnob.Value = 1;
        end

        % Button pushed function: ResetButton_3
        function ResetButton_3Pushed(app, event)
            app.AccelerationKnob.Value = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image_6 = uiimage(app.UIFigure);
            app.Image_6.ImageSource = fullfile(pathToMLAPP, 'タイトルなし.png');
            app.Image_7 = uiimage(app.UIFigure);
            app.Image_7.ImageSource = fullfile(pathToMLAPP, 'タイトルなし.png');
            app.Image_8 = uiimage(app.UIFigure);
            app.Image_8.ImageSource = fullfile(pathToMLAPP, 'タイトルなし.png');
            app.Image_9 = uiimage(app.UIFigure);
            app.Image_9.ImageSource = fullfile(pathToMLAPP, 'タイトルなし.png');
            app.Image_10 = uiimage(app.UIFigure);
            app.Image_10.ImageSource = fullfile(pathToMLAPP, 'タイトルなし.png');
            app.Image_11 = uiimage(app.UIFigure);
            app.Image_11.ImageSource = fullfile(pathToMLAPP, 'タイトルなし.png');
            app.Image_12 = uiimage(app.UIFigure);
            app.Image_12.ImageSource = fullfile(pathToMLAPP, 'タイトルなし.png');
            app.Image_13 = uiimage(app.UIFigure);
            app.Image_13.ImageSource = fullfile(pathToMLAPP, 'タイトルなし.png');
            app.Image_5 = uiimage(app.UIFigure);
            app.Image_5.ImageSource = fullfile(pathToMLAPP, 'タイトルなし.png');
            app.Image_4 = uiimage(app.UIFigure);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'タイトルなし.png');
            app.Image_3 = uiimage(app.UIFigure);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'タイトルなし.png');
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'タイトルなし.png');
            app.Image_14 = uiimage(app.UIFigure);
            app.Image_14.ImageSource = fullfile(pathToMLAPP, 'タイトルなし.png');
            app.Image_15 = uiimage(app.UIFigure);
            app.Image_15.ImageSource = fullfile(pathToMLAPP, 'タイトルなし.png');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'タイトルなし.png');
            app.Switch_1 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_1.Items = {'off', 'on'};
            app.Switch_1.Tag = 'Switch1';
            app.Switch_1.Value = 'off';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [1 16];
            app.Slider.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16];
            app.Slider.Tag = 'Slider';
            app.Slider.Value = 1;
            app.Switch_2 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_2.Items = {'off', 'on'};
            app.Switch_2.Tag = 'Switch2';
            app.Switch_2.Value = 'off';
            app.Switch_3 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_3.Items = {'off', 'on'};
            app.Switch_3.Tag = 'Switch3';
            app.Switch_3.Value = 'off';
            app.Switch_4 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_4.Items = {'off', 'on'};
            app.Switch_4.Tag = 'Switch4';
            app.Switch_4.Value = 'off';
            app.BPMKnobLabel = uilabel(app.UIFigure);
            app.BPMKnobLabel.Text = 'BPM';
            app.BPMKnob = uiknob(app.UIFigure, 'continuous');
            app.BPMKnob.Limits = [1 200];
            app.BPMKnob.MajorTicks = [1 40 80 120 160 200];
            app.BPMKnob.Value = 1;
            app.Switch_5 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_5.Items = {'off', 'on'};
            app.Switch_5.Tag = 'Switch6';
            app.Switch_5.Value = 'off';
            app.Switch_6 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_6.Items = {'off', 'on'};
            app.Switch_6.Tag = 'Switch7';
            app.Switch_6.Value = 'off';
            app.Switch_7 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_7.Items = {'off', 'on'};
            app.Switch_7.Tag = 'Switch8';
            app.Switch_7.Value = 'off';
            app.Switch_8 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_8.Items = {'off', 'on'};
            app.Switch_8.Tag = 'Switch9';
            app.Switch_8.Value = 'off';
            app.Switch_9 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_9.Items = {'off', 'on'};
            app.Switch_9.Tag = 'Switch10';
            app.Switch_9.Value = 'off';
            app.Switch_10 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_10.Items = {'off', 'on'};
            app.Switch_10.Tag = 'Switch11';
            app.Switch_10.Value = 'off';
            app.Switch_11 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_11.Items = {'off', 'on'};
            app.Switch_11.Tag = 'Switch12';
            app.Switch_11.Value = 'off';
            app.Switch_12 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_12.Items = {'off', 'on'};
            app.Switch_12.Tag = 'Switch13';
            app.Switch_12.Value = 'off';
            app.Switch_13 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_13.Items = {'off', 'on'};
            app.Switch_13.Tag = 'Switch14';
            app.Switch_13.Value = 'off';
            app.Switch_14 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_14.Items = {'off', 'on'};
            app.Switch_14.Tag = 'Switch15';
            app.Switch_14.Value = 'off';
            app.Switch_15 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_15.Items = {'off', 'on'};
            app.Switch_15.Tag = 'Switch16';
            app.Switch_15.Value = 'off';
            app.Switch_16 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_16.Items = {'off', 'on'};
            app.Switch_16.Tag = 'Switch17';
            app.Switch_16.Value = 'off';
            app.Switch_17 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_17.Items = {'off', 'on'};
            app.Switch_17.Tag = 'Switch18';
            app.Switch_17.Value = 'off';
            app.Switch_18 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_18.Items = {'off', 'on'};
            app.Switch_18.Tag = 'Switch19';
            app.Switch_18.Value = 'off';
            app.Switch_19 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_19.Items = {'off', 'on'};
            app.Switch_19.Tag = 'Switch20';
            app.Switch_19.Value = 'off';
            app.Switch_20 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_20.Items = {'off', 'on'};
            app.Switch_20.Tag = 'Switch21';
            app.Switch_20.Value = 'off';
            app.Switch_21 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_21.Items = {'off', 'on'};
            app.Switch_21.Tag = 'Switch22';
            app.Switch_21.Value = 'off';
            app.Switch_22 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_22.Items = {'off', 'on'};
            app.Switch_22.Tag = 'Switch23';
            app.Switch_22.Value = 'off';
            app.Switch_23 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_23.Items = {'off', 'on'};
            app.Switch_23.Tag = 'Switch24';
            app.Switch_23.Value = 'off';
            app.Switch_24 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_24.Items = {'off', 'on'};
            app.Switch_24.Tag = 'Switch25';
            app.Switch_24.Value = 'off';
            app.Switch_25 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_25.Items = {'off', 'on'};
            app.Switch_25.Tag = 'Switch26';
            app.Switch_25.Value = 'off';
            app.Switch_26 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_26.Items = {'off', 'on'};
            app.Switch_26.Tag = 'Switch27';
            app.Switch_26.Value = 'off';
            app.Switch_27 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_27.Items = {'off', 'on'};
            app.Switch_27.Tag = 'Switch28';
            app.Switch_27.Value = 'off';
            app.Switch_28 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_28.Items = {'off', 'on'};
            app.Switch_28.Tag = 'Switch29';
            app.Switch_28.Value = 'off';
            app.Switch_29 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_29.Items = {'off', 'on'};
            app.Switch_29.Tag = 'Switch30';
            app.Switch_29.Value = 'off';
            app.Switch_30 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_30.Items = {'off', 'on'};
            app.Switch_30.Tag = 'Switch31';
            app.Switch_30.Value = 'off';
            app.Switch_31 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_31.Items = {'off', 'on'};
            app.Switch_31.Tag = 'Switch32';
            app.Switch_31.Value = 'off';
            app.Switch_32 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_32.Items = {'off', 'on'};
            app.Switch_32.Tag = 'Switch33';
            app.Switch_32.Value = 'off';
            app.Switch_33 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_33.Items = {'off', 'on'};
            app.Switch_33.Tag = 'Switch34';
            app.Switch_33.Value = 'off';
            app.Switch_34 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_34.Items = {'off', 'on'};
            app.Switch_34.Tag = 'Switch35';
            app.Switch_34.Value = 'off';
            app.Switch_35 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_35.Items = {'off', 'on'};
            app.Switch_35.Tag = 'Switch36';
            app.Switch_35.Value = 'off';
            app.Switch_36 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_36.Items = {'off', 'on'};
            app.Switch_36.Tag = 'Switch37';
            app.Switch_36.Value = 'off';
            app.Switch_37 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_37.Items = {'off', 'on'};
            app.Switch_37.Tag = 'Switch38';
            app.Switch_37.Value = 'off';
            app.Switch_38 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_38.Items = {'off', 'on'};
            app.Switch_38.Tag = 'Switch39';
            app.Switch_38.Value = 'off';
            app.Switch_39 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_39.Items = {'off', 'on'};
            app.Switch_39.Tag = 'Switch40';
            app.Switch_39.Value = 'off';
            app.Switch_40 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_40.Items = {'off', 'on'};
            app.Switch_40.Tag = 'Switch41';
            app.Switch_40.Value = 'off';
            app.Switch_41 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_41.Items = {'off', 'on'};
            app.Switch_41.Tag = 'Switch42';
            app.Switch_41.Value = 'off';
            app.Switch_42 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_42.Items = {'off', 'on'};
            app.Switch_42.Tag = 'Switch43';
            app.Switch_42.Value = 'off';
            app.Switch_43 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_43.Items = {'off', 'on'};
            app.Switch_43.Tag = 'Switch44';
            app.Switch_43.Value = 'off';
            app.Switch_44 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_44.Items = {'off', 'on'};
            app.Switch_44.Tag = 'Switch45';
            app.Switch_44.Value = 'off';
            app.Switch_45 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_45.Items = {'off', 'on'};
            app.Switch_45.Tag = 'Switch46';
            app.Switch_45.Value = 'off';
            app.Switch_46 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_46.Items = {'off', 'on'};
            app.Switch_46.Tag = 'Switch47';
            app.Switch_46.Value = 'off';
            app.Switch_47 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_47.Items = {'off', 'on'};
            app.Switch_47.Tag = 'Switch48';
            app.Switch_47.Value = 'off';
            app.Switch_48 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_48.Items = {'off', 'on'};
            app.Switch_48.Tag = 'Switch49';
            app.Switch_48.Value = 'off';
            app.Switch_49 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_49.Items = {'off', 'on'};
            app.Switch_49.Tag = 'Switch50';
            app.Switch_49.Value = 'off';
            app.Switch_50 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_50.Items = {'off', 'on'};
            app.Switch_50.Tag = 'Switch51';
            app.Switch_50.Value = 'off';
            app.Switch_51 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_51.Items = {'off', 'on'};
            app.Switch_51.Tag = 'Switch52';
            app.Switch_51.Value = 'off';
            app.Switch_52 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_52.Items = {'off', 'on'};
            app.Switch_52.Tag = 'Switch53';
            app.Switch_52.Value = 'off';
            app.Switch_53 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_53.Items = {'off', 'on'};
            app.Switch_53.Tag = 'Switch54';
            app.Switch_53.Value = 'off';
            app.Switch_54 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_54.Items = {'off', 'on'};
            app.Switch_54.Tag = 'Switch55';
            app.Switch_54.Value = 'off';
            app.Switch_55 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_55.Items = {'off', 'on'};
            app.Switch_55.Tag = 'Switch5956';
            app.Switch_55.Value = 'off';
            app.Switch_56 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_56.Items = {'off', 'on'};
            app.Switch_56.Tag = 'Switch57';
            app.Switch_56.Value = 'off';
            app.Switch_57 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_57.Items = {'off', 'on'};
            app.Switch_57.Tag = 'Switch58';
            app.Switch_57.Value = 'off';
            app.Switch_58 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_58.Items = {'off', 'on'};
            app.Switch_58.Tag = 'Switch59';
            app.Switch_58.Value = 'off';
            app.Switch_59 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_59.Items = {'off', 'on'};
            app.Switch_59.Tag = 'Switch60';
            app.Switch_59.Value = 'off';
            app.Switch_60 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_60.Items = {'off', 'on'};
            app.Switch_60.Tag = 'Switch61';
            app.Switch_60.Value = 'off';
            app.Switch_61 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_61.Items = {'off', 'on'};
            app.Switch_61.Tag = 'Switch62';
            app.Switch_61.Value = 'off';
            app.Switch_62 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_62.Items = {'off', 'on'};
            app.Switch_62.Tag = 'Switch63';
            app.Switch_62.Value = 'off';
            app.Switch_63 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_63.Items = {'off', 'on'};
            app.Switch_63.Tag = 'Switch64';
            app.Switch_63.Value = 'off';
            app.Switch_64 = uiswitch(app.UIFigure, 'toggle');
            app.Switch_64.Items = {'off', 'on'};
            app.Switch_64.Value = 'off';
            app.AccelerationKnobLabel = uilabel(app.UIFigure);
            app.AccelerationKnobLabel.Text = 'Acceleration (%)';
            app.AccelerationKnob = uiknob(app.UIFigure, 'continuous');
            app.AccelerationKnob.Limits = [-100 100];
            app.AccelerationKnob.MajorTicks = [-100 -90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90 100];
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ClapLabel = uilabel(app.UIFigure);
            app.ClapLabel.Text = 'Clap';
            app.HighhatLabel = uilabel(app.UIFigure);
            app.HighhatLabel.Text = 'High-hat';
            app.SnaredrumLabel = uilabel(app.UIFigure);
            app.SnaredrumLabel.Text = 'Snare drum';
            app.BassdrumLabel = uilabel(app.UIFigure);
            app.BassdrumLabel.Text = 'Bass drum';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Tag = 'Stop';
            app.StopButton.Text = 'Stop';
            app.Lamp = uilamp(app.UIFigure);
            app.ResetBPMButton = uibutton(app.UIFigure, 'push');
            app.ResetBPMButton.ButtonPushedFcn = createCallbackFcn(app, @ResetBPMButtonPushed, true);
            app.ResetBPMButton.Text = 'Reset BPM';
            app.ResetButton_3 = uibutton(app.UIFigure, 'push');
            app.ResetButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_3Pushed, true);
            app.ResetButton_3.Text = 'Reset %';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end